classdef prototipe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        EsferasTab              matlab.ui.container.Tab
        Switch                  matlab.ui.control.Switch
        Panel                   matlab.ui.container.Panel
        cb100                   matlab.ui.control.CheckBox
        cb90                    matlab.ui.control.CheckBox
        cb80                    matlab.ui.control.CheckBox
        cb70                    matlab.ui.control.CheckBox
        cb60                    matlab.ui.control.CheckBox
        cb50                    matlab.ui.control.CheckBox
        cb40                    matlab.ui.control.CheckBox
        cb30                    matlab.ui.control.CheckBox
        cb20                    matlab.ui.control.CheckBox
        cb10                    matlab.ui.control.CheckBox
        dropDownSecond          matlab.ui.control.DropDown
        dropDownFirst           matlab.ui.control.DropDown
        campoLabel              matlab.ui.control.Label
        ConstantesopticasPanel  matlab.ui.container.Panel
        dropDownConst           matlab.ui.control.DropDown
        imagenCampo             matlab.ui.control.Image
        figura                  matlab.ui.control.UIAxes
        BarrasTab               matlab.ui.container.Tab
    end

    properties (Access = private)
        waveOro10  = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 1); qAbs10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 2); qSca10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 3); qExt10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 4);
        waveOro20  = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 1); qAbs20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 2); qSca20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 3); qExt20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 4);
        waveOro30  = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 1); qAbs30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 2); qSca30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 3); qExt30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 4);
        waveOro40  = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 1); qAbs40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 2); qSca40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 3); qExt40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 4);
        waveOro50  = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 1); qAbs50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 2); qSca50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 3); qExt50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 4);
        waveOro60  = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 1); qAbs60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 2); qSca60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 3); qExt60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 4);
        waveOro70  = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 1); qAbs70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 2); qSca70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 3); qExt70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 4);
        waveOro80  = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 1); qAbs80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 2); qSca80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 3); qExt80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 4);
        waveOro90  = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 1); qAbs90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 2); qSca90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 3); qExt90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 4);
        waveOro100  = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 1); qAbs100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 2); qSca100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 3); qExt100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 4);
        OA10; OA20; OA30; OA40; OA50; OA60; OA70; OA80; OA90; OA100;
        OS10; OS20; OS30; OS40; OS50; OS60; OS70; OS80; OS90; OS100;
        OE10; OE20; OE30; OE40; OE50; OE60; OE70; OE80; OE90; OE100;
        %Plata10 = importdata('data/Plata/comsol_plata_10_nm.txt', ',', 1); wavePlata10  = Plata10.data(:, 1); qAbsP10 = Plata10.data(:, 2); qScaP10 = Plata10.data(:, 3); qExtP10 = Plata10.data(:, 4);
        %Plata20 = importdata('data/Plata/comsol_plata_20_nm.txt', ',', 1); wavePlata20  = Plata20.data(:, 1); qAbsP20 = Plata20.data(:, 2); qScaP20 = Plata20.data(:, 3); qExtP20 = Plata20.data(:, 4);
        %Plata30 = importdata('data/Plata/comsol_plata_30_nm.txt', ',', 1); wavePlata30  = Plata30.data(:, 1); qAbsP30 = Plata30.data(:, 2); qScaP30 = Plata30.data(:, 3); qExtP30 = Plata30.data(:, 4);
        %Plata40 = importdata('data/Plata/comsol_plata_40_nm.txt', ',', 1); wavePlata40  = Plata40.data(:, 1); qAbsP40 = Plata40.data(:, 2); qScaP40 = Plata40.data(:, 3); qExtP40 = Plata40.data(:, 4);
        %Plata50 = importdata('data/Plata/comsol_plata_50_nm.txt', ',', 1); wavePlata50  = Plata50.data(:, 1); qAbsP50 = Plata50.data(:, 2); qScaP50 = Plata50.data(:, 3); qExtP50 = Plata50.data(:, 4);
        %Plata60 = importdata('data/Plata/comsol_plata_60_nm.txt', ',', 1); wavePlata60  = Plata60.data(:, 1); qAbsP60 = Plata60.data(:, 2); qScaP60 = Plata60.data(:, 3); qExtP60 = Plata60.data(:, 4);
        %Plata70 = importdata('data/Plata/comsol_plata_70_nm.txt', ',', 1); wavePlata70  = Plata70.data(:, 1); qAbsP70 = Plata70.data(:, 2); qScaP70 = Plata70.data(:, 3); qExtP70 = Plata70.data(:, 4);
        %Plata80 = importdata('data/Plata/comsol_plata_80_nm.txt', ',', 1); wavePlata80  = Plata80.data(:, 1); qAbsP80 = Plata80.data(:, 2); qScaP80 = Plata80.data(:, 3); qExtP80 = Plata80.data(:, 4);
        %Plata90 = importdata('data/Plata/comsol_plata_90_nm.txt', ',', 1); wavePlata90  = Plata90.data(:, 1); qAbsP90 = Plata90.data(:, 2); qScaP90 = Plata90.data(:, 3); qExtP90 = Plata90.data(:, 4);
        %Plata100 = importdata('data/Plata/comsol_plata_100_nm.txt', ',', 1); wavePlata100  = Plata100.data(:, 1); qAbsP100 = Plata100.data(:, 2); qScaP100 = Plata100.data(:, 3); qExtP100 = Plata100.data(:, 4);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            cla(app.figura);
            app.figura.Title.String = "";
            xlabel(app.figura, "");
            ylabel(app.figura, "");
            legend(app.figura, "off");
            app.dropDownConst.Value = "";
            app.dropDownFirst.Value = "";
            app.dropDownSecond.Value = "";
            app.dropDownSecond.Enable = "off";
            app.cb10.Enable = "off"; app.cb10.Value = 0;
            app.cb20.Enable = "off"; app.cb20.Value = 0;
            app.cb30.Enable = "off"; app.cb30.Value = 0;
            app.cb40.Enable = "off"; app.cb40.Value = 0;
            app.cb50.Enable = "off"; app.cb50.Value = 0;
            app.cb60.Enable = "off"; app.cb60.Value = 0;
            app.cb70.Enable = "off"; app.cb70.Value = 0;
            app.cb80.Enable = "off"; app.cb80.Value = 0;
            app.cb90.Enable = "off"; app.cb90.Value = 0;
            app.cb100.Enable = "off"; app.cb100.Value = 0;
            if value == "Constantes ópticas"
                app.Panel.Enable ="off";
                app.ConstantesopticasPanel.Enable = "on";
            else
                app.Panel.Enable ="on";
                app.ConstantesopticasPanel.Enable = "off";  
            end
        end

        % Value changed function: dropDownFirst
        function dropDownFirstValueChanged(app, event)
            value = app.dropDownFirst.Value;
            cla(app.figura);
            app.figura.Title.String = "";
            xlabel(app.figura, "");
            ylabel(app.figura, "");
            legend(app.figura, "off");
            app.dropDownSecond.Value = "";
            app.cb10.Enable = "off"; app.cb10.Value = 0;
            app.cb20.Enable = "off"; app.cb20.Value = 0;
            app.cb30.Enable = "off"; app.cb30.Value = 0;
            app.cb40.Enable = "off"; app.cb40.Value = 0;
            app.cb50.Enable = "off"; app.cb50.Value = 0;
            app.cb60.Enable = "off"; app.cb60.Value = 0;
            app.cb70.Enable = "off"; app.cb70.Value = 0;
            app.cb80.Enable = "off"; app.cb80.Value = 0;
            app.cb90.Enable = "off"; app.cb90.Value = 0;
            app.cb100.Enable = "off"; app.cb100.Value = 0;
            if value ~= ""
                app.dropDownSecond.Enable = "on";
            else
                app.dropDownSecond.Enable = "off";
            end
        end

        % Value changed function: dropDownSecond
        function dropDownSecondValueChanged(app, event)
            value = app.dropDownSecond.Value;
            xlabel(app.figura, "Longitud de onda $({\lambda}) \ [nm]$", "Interpreter", "latex");
            ylabel(app.figura, "Secci\'on Eficaz ($m^2$)", "Interpreter", "latex");
            xlim(app.figura, [400, 800]);
            if value ~= ""
                app.cb10.Enable = "on"; app.cb10.Value = 1;
                app.cb20.Enable = "on"; app.cb20.Value = 1;
                app.cb30.Enable = "on"; app.cb30.Value = 1;
                app.cb40.Enable = "on"; app.cb40.Value = 1;
                app.cb50.Enable = "on"; app.cb50.Value = 1;
                app.cb60.Enable = "on"; app.cb60.Value = 1;
                app.cb70.Enable = "on"; app.cb70.Value = 1;
                app.cb80.Enable = "on"; app.cb80.Value = 1;
                app.cb90.Enable = "on"; app.cb90.Value = 1;
                app.cb100.Enable = "on"; app.cb100.Value = 1;
                if app.dropDownFirst.Value == "Oro"
                    if value == "Absorción simulada"
                        title(app.figura, "Absorci\'on simulada - Oro", "Interpreter", "latex");
                        app.OA10 = plot(app.figura, app.waveOro10, app.qAbs10);
                        hold(app.figura, "on");
                        app.OA20 = plot(app.figura, app.waveOro20, app.qAbs20);
                        hold(app.figura, "on");
                        app.OA30 = plot(app.figura, app.waveOro30, app.qAbs30);
                        hold(app.figura, "on");
                        app.OA40 = plot(app.figura, app.waveOro40, app.qAbs40);
                        hold(app.figura, "on");
                        app.OA50 = plot(app.figura, app.waveOro50, app.qAbs50);
                        hold(app.figura, "on");
                        app.OA60 = plot(app.figura, app.waveOro60, app.qAbs60);
                        hold(app.figura, "on");
                        app.OA70 = plot(app.figura, app.waveOro70, app.qAbs70);
                        hold(app.figura, "on");
                        app.OA80 = plot(app.figura, app.waveOro80, app.qAbs80);
                        hold(app.figura, "on");
                        app.OA90 = plot(app.figura, app.waveOro90, app.qAbs90);
                        hold(app.figura, "on");
                        app.OA100 = plot(app.figura, app.waveOro100, app.qAbs100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                    if value == "Dispersión simulada"
                        title(app.figura, "Dispersi\'on simulada - Oro", "Interpreter", "latex");
                        app.OS10 = plot(app.figura, app.waveOro10, app.qSca10);
                        hold(app.figura, "on");
                        app.OS20 = plot(app.figura, app.waveOro20, app.qSca20);
                        hold(app.figura, "on");
                        app.OS30 = plot(app.figura, app.waveOro30, app.qSca30);
                        hold(app.figura, "on");
                        app.OS40 = plot(app.figura, app.waveOro40, app.qSca40);
                        hold(app.figura, "on");
                        app.OS50 = plot(app.figura, app.waveOro50, app.qSca50);
                        hold(app.figura, "on");
                        app.OS60 = plot(app.figura, app.waveOro60, app.qSca60);
                        hold(app.figura, "on");
                        app.OS70 = plot(app.figura, app.waveOro70, app.qSca70);
                        hold(app.figura, "on");
                        app.OS80 = plot(app.figura, app.waveOro80, app.qSca80);
                        hold(app.figura, "on");
                        app.OS90 = plot(app.figura, app.waveOro90, app.qSca90);
                        hold(app.figura, "on");
                        app.OS100 = plot(app.figura, app.waveOro100, app.qSca100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                    if value == "Extinción simulada"
                        title(app.figura, "Extinci\'on simulada - Oro", "Interpreter", "latex");
                        app.OE10 = plot(app.figura, app.waveOro10, app.qExt10);
                        hold(app.figura, "on");
                        app.OE20 = plot(app.figura, app.waveOro20, app.qExt20);
                        hold(app.figura, "on");
                        app.OE30 = plot(app.figura, app.waveOro30, app.qExt30);
                        hold(app.figura, "on");
                        app.OE40 = plot(app.figura, app.waveOro40, app.qExt40);
                        hold(app.figura, "on");
                        app.OE50 = plot(app.figura, app.waveOro50, app.qExt50);
                        hold(app.figura, "on");
                        app.OE60 = plot(app.figura, app.waveOro60, app.qExt60);
                        hold(app.figura, "on");
                        app.OE70 = plot(app.figura, app.waveOro70, app.qExt70);
                        hold(app.figura, "on");
                        app.OE80 = plot(app.figura, app.waveOro80, app.qExt80);
                        hold(app.figura, "on");
                        app.OE90 = plot(app.figura, app.waveOro90, app.qExt90);
                        hold(app.figura, "on");
                        app.OE100 = plot(app.figura, app.waveOro100, app.qExt100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if value == "Absorción simulada"
                        title(app.figura, "Absorci\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figura, [300, 800]);
                        plot(app.figura, wavePlata10, qAbsP10);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata20, qAbsP20);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata30, qAbsP30);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata40, qAbsP40);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata50, qAbsP50);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata60, qAbsP60);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata70, qAbsP70);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata80, qAbsP80);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata90, qAbsP90);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata100, qAbsP100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                    if value == "Dispersión simulada"
                        title(app.figura, "Dispersi\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figura, [300, 800]);
                        plot(app.figura, wavePlata10, qScaP10);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata20, qScaP20);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata30, qScaP30);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata40, qScaP40);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata50, qScaP50);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata60, qScaP60);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata70, qScaP70);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata80, qScaP80);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata90, qScaP90);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata100, qScaP100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                    if value == "Extinción simulada"
                        title(app.figura, "Extinci\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figura, [300, 800]);
                        plot(app.figura, wavePlata10, qExtP10);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata20, qExtP20);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata30, qExtP30);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata40, qExtP40);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata50, qExtP50);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata60, qExtP60);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata70, qExtP70);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata80, qExtP80);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata90, qExtP90);
                        hold(app.figura, "on");
                        plot(app.figura, wavePlata100, qExtP100);
                        legend(app.figura, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figura, "off");
                    end
                end
            else
                cla(app.figura);
                app.figura.Title.String = "";
                xlabel(app.figura, "");
                ylabel(app.figura, "");
                legend(app.figura, "off");
                app.cb10.Enable = "off"; app.cb10.Value = 0;
                app.cb20.Enable = "off"; app.cb20.Value = 0;
                app.cb30.Enable = "off"; app.cb30.Value = 0;
                app.cb40.Enable = "off"; app.cb40.Value = 0;
                app.cb50.Enable = "off"; app.cb50.Value = 0;
                app.cb60.Enable = "off"; app.cb60.Value = 0;
                app.cb70.Enable = "off"; app.cb70.Value = 0;
                app.cb80.Enable = "off"; app.cb80.Value = 0;
                app.cb90.Enable = "off"; app.cb90.Value = 0;
                app.cb100.Enable = "off"; app.cb100.Value = 0;
            end
        end

        % Value changed function: dropDownConst
        function dropDownConstValueChanged(app, event)
            value = app.dropDownConst.Value;
            % app.figura.TitleFontSizeMultiplier = 1.55;
            xlabel(app.figura, "Longitud de onda $({\lambda}) \ [nm]$", "Interpreter", "latex");
            ylabel(app.figura, "Valor de la constante", "Interpreter", "latex");
            xlim(app.figura, [200, 1000]);
            filenameOroConstantes = 'data/index/indexoro.txt';
            filenamePlataConstantes = 'data/index/indexplata.txt';
            delimiterIn = ' ';
            headerlinesIn = 1;
            oroConstantes = importdata(filenameOroConstantes, delimiterIn, headerlinesIn);
            plataConstantes = importdata(filenamePlataConstantes, delimiterIn, headerlinesIn);
            waveOroConstantes = oroConstantes.data(:, 1);
            wavePlataConstantes = plataConstantes.data(:, 1);
            nO = oroConstantes.data(:, 2);
            nP = plataConstantes.data(:, 2);
            kO = oroConstantes.data(:, 3);
            kP = plataConstantes.data(:, 3);
            if value == ""
               cla(app.figura);
               app.figura.Title.String = "";
               xlabel(app.figura, "");
               ylabel(app.figura, "");
               legend(app.figura, "off");
            end
            if value == "Oro"
               title(app.figura, value, "Interpreter", "latex");
               plot(app.figura, waveOroConstantes*1000, nO);
               hold(app.figura, "on");
               plot(app.figura, waveOroConstantes*1000, kO);
               legend(app.figura, ["\'Indice de Refracci\'on (n)", "Coeficiente de extinci\'on (k)"], "Interpreter", "latex");
               hold(app.figura, "off");
            end
            if value == "Plata"
               title(app.figura, value, "Interpreter", "latex");
               plot(app.figura, wavePlataConstantes*1000, nP);
               hold(app.figura, "on");
               plot(app.figura, wavePlataConstantes*1000, kP);
               legend(app.figura, ["\'Indice de Refracci\'on (n)", "Coeficiente de extinci\'on (k)"], "Interpreter", "latex");
               hold(app.figura, "off");
            end
        end

        % Value changed function: cb10
        function cb10ValueChanged(app, event)
            value = app.cb10.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE10.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE10.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb20
        function cb20ValueChanged(app, event)
            value = app.cb20.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE20.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE20.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb30
        function cb30ValueChanged(app, event)
            value = app.cb30.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE30.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE30.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb40
        function cb40ValueChanged(app, event)
            value = app.cb40.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE40.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE40.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb50
        function cb50ValueChanged(app, event)
            value = app.cb50.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE50.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE50.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb60
        function cb60ValueChanged(app, event)
            value = app.cb60.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE60.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE60.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb70
        function cb70ValueChanged(app, event)
            value = app.cb70.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE70.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE70.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb80
        function cb80ValueChanged(app, event)
            value = app.cb80.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE80.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE80.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb90
        function cb90ValueChanged(app, event)
            value = app.cb90.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE90.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE90.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb100
        function cb100ValueChanged(app, event)
            value = app.cb100.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE100.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE100.Visible = "on";
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EsferasTab = uitab(app.TabGroup);
            app.EsferasTab.Title = 'Esferas';
            app.figura = uiaxes(app.EsferasTab);
            zlabel(app.figura, 'Z')
            app.imagenCampo = uiimage(app.EsferasTab);
            app.ConstantesopticasPanel = uipanel(app.EsferasTab);
            app.ConstantesopticasPanel.Title = 'Constantes ópticas';
            app.dropDownConst = uidropdown(app.ConstantesopticasPanel);
            app.dropDownConst.Items = {'', 'Oro', 'Plata'};
            app.dropDownConst.ValueChangedFcn = createCallbackFcn(app, @dropDownConstValueChanged, true);
            app.dropDownConst.Value = '';
            app.campoLabel = uilabel(app.EsferasTab);
            app.campoLabel.Text = 'Campo eléctrico cercano';
            app.Panel = uipanel(app.EsferasTab);
            app.Panel.Enable = 'off';
            app.dropDownFirst = uidropdown(app.Panel);
            app.dropDownFirst.Items = {'', 'Oro', 'Plata'};
            app.dropDownFirst.ValueChangedFcn = createCallbackFcn(app, @dropDownFirstValueChanged, true);
            app.dropDownFirst.Placeholder = ' ';
            app.dropDownFirst.Value = '';
            app.dropDownSecond = uidropdown(app.Panel);
            app.dropDownSecond.Items = {'', 'Absorción simulada', 'Dispersión simulada', 'Extinción simulada'};
            app.dropDownSecond.ValueChangedFcn = createCallbackFcn(app, @dropDownSecondValueChanged, true);
            app.dropDownSecond.Enable = 'off';
            app.dropDownSecond.Value = '';
            app.cb10 = uicheckbox(app.Panel);
            app.cb10.ValueChangedFcn = createCallbackFcn(app, @cb10ValueChanged, true);
            app.cb10.Enable = 'off';
            app.cb10.Text = '10';
            app.cb20 = uicheckbox(app.Panel);
            app.cb20.ValueChangedFcn = createCallbackFcn(app, @cb20ValueChanged, true);
            app.cb20.Enable = 'off';
            app.cb20.Text = '20';
            app.cb30 = uicheckbox(app.Panel);
            app.cb30.ValueChangedFcn = createCallbackFcn(app, @cb30ValueChanged, true);
            app.cb30.Enable = 'off';
            app.cb30.Text = '30';
            app.cb40 = uicheckbox(app.Panel);
            app.cb40.ValueChangedFcn = createCallbackFcn(app, @cb40ValueChanged, true);
            app.cb40.Enable = 'off';
            app.cb40.Text = '40';
            app.cb50 = uicheckbox(app.Panel);
            app.cb50.ValueChangedFcn = createCallbackFcn(app, @cb50ValueChanged, true);
            app.cb50.Enable = 'off';
            app.cb50.Text = '50';
            app.cb60 = uicheckbox(app.Panel);
            app.cb60.ValueChangedFcn = createCallbackFcn(app, @cb60ValueChanged, true);
            app.cb60.Enable = 'off';
            app.cb60.Text = '60';
            app.cb70 = uicheckbox(app.Panel);
            app.cb70.ValueChangedFcn = createCallbackFcn(app, @cb70ValueChanged, true);
            app.cb70.Enable = 'off';
            app.cb70.Text = '70';
            app.cb80 = uicheckbox(app.Panel);
            app.cb80.ValueChangedFcn = createCallbackFcn(app, @cb80ValueChanged, true);
            app.cb80.Enable = 'off';
            app.cb80.Text = '80';
            app.cb90 = uicheckbox(app.Panel);
            app.cb90.ValueChangedFcn = createCallbackFcn(app, @cb90ValueChanged, true);
            app.cb90.Enable = 'off';
            app.cb90.Text = '90';
            app.cb100 = uicheckbox(app.Panel);
            app.cb100.ValueChangedFcn = createCallbackFcn(app, @cb100ValueChanged, true);
            app.cb100.Enable = 'off';
            app.cb100.Text = '100';
            app.Switch = uiswitch(app.EsferasTab, 'slider');
            app.Switch.Items = {'Constantes ópticas', 'La otra vaina'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Constantes ópticas';
            app.BarrasTab = uitab(app.TabGroup);
            app.BarrasTab.Title = 'Barras';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototipe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end